classdef app3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        Joint4Slider_2       matlab.ui.control.Slider
        Joint4Slider_2Label  matlab.ui.control.Label
        Joint3Slider_2       matlab.ui.control.Slider
        Joint3Slider_2Label  matlab.ui.control.Label
        Joint2Slider_2       matlab.ui.control.Slider
        Joint2Slider_2Label  matlab.ui.control.Label
        Joint1Slider_2       matlab.ui.control.Slider
        Joint1Slider_2Label  matlab.ui.control.Label
        THORLabel            matlab.ui.control.Label
        DOBOTLabel           matlab.ui.control.Label
        ExitBackHomeButton   matlab.ui.control.Button
        Joint4Slider         matlab.ui.control.Slider
        Joint4SliderLabel    matlab.ui.control.Label
        Joint3Slider         matlab.ui.control.Slider
        Joint3SliderLabel    matlab.ui.control.Label
        Joint2Slider         matlab.ui.control.Slider
        Joint2SliderLabel    matlab.ui.control.Label
        Joint1Slider         matlab.ui.control.Slider
        Joint1SliderLabel    matlab.ui.control.Label
        JointAnglesLabel     matlab.ui.control.Label
    end

    
    properties (Access = private)
        % place your class properties here
        modelPuma % Where I will put the loaded puma
        modelDobot % Where I will put the loaded UR5
        qz = [0     0     0     0     0     0];
        
        qrDobot = [0	pi/4  pi/4	pi/2	0	0];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function init(app, varargin)
 % Code to run once GUI is loaded successfully
            % Code to run once GUI is loaded successfully
            ax = gca;

            % Make, save and plot some robots            
                  

            app.modelDobot = DobotMagician;
            % app.modelDobot.model.base = transl([-0.5,0,0]);
            app.modelDobot.model.animate(app.qrDobot);
            drawnow();

            % Delete the green floor surface (need to count backwards
            % because once it is deleted the array is compressed)
            for i = size(ax.Children,1):-1:1
                if strcmp(ax.Children(i).Type,'surface')
                    delete(ax.Children(i));
                end
            end
            
            % Turn off this load button and enable the move button
 
            
        end

        % Button pushed function: ExitBackHomeButton
        function ExitBackHomeButtonPushed(app, event)
            app1
          
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.JointAnglesLabel = uilabel(app.UIFigure);
            app.JointAnglesLabel.Text = 'Joint Angles ';
            app.Joint1SliderLabel = uilabel(app.UIFigure);
            app.Joint1SliderLabel.Text = 'Joint 1';
            app.Joint1Slider = uislider(app.UIFigure);
            app.Joint2SliderLabel = uilabel(app.UIFigure);
            app.Joint2SliderLabel.Text = 'Joint 2';
            app.Joint2Slider = uislider(app.UIFigure);
            app.Joint3SliderLabel = uilabel(app.UIFigure);
            app.Joint3SliderLabel.Text = 'Joint 3';
            app.Joint3Slider = uislider(app.UIFigure);
            app.Joint4SliderLabel = uilabel(app.UIFigure);
            app.Joint4SliderLabel.Text = 'Joint 4';
            app.Joint4Slider = uislider(app.UIFigure);
            app.ExitBackHomeButton = uibutton(app.UIFigure, 'push');
            app.ExitBackHomeButton.ButtonPushedFcn = createCallbackFcn(app, @ExitBackHomeButtonPushed, true);
            app.ExitBackHomeButton.Text = 'Exit Back Home';
            app.DOBOTLabel = uilabel(app.UIFigure);
            app.DOBOTLabel.Text = 'DOBOT';
            app.THORLabel = uilabel(app.UIFigure);
            app.THORLabel.Text = 'THOR';
            app.Joint1Slider_2Label = uilabel(app.UIFigure);
            app.Joint1Slider_2Label.Text = 'Joint 1';
            app.Joint1Slider_2 = uislider(app.UIFigure);
            app.Joint2Slider_2Label = uilabel(app.UIFigure);
            app.Joint2Slider_2Label.Text = 'Joint 2';
            app.Joint2Slider_2 = uislider(app.UIFigure);
            app.Joint3Slider_2Label = uilabel(app.UIFigure);
            app.Joint3Slider_2Label.Text = 'Joint 3';
            app.Joint3Slider_2 = uislider(app.UIFigure);
            app.Joint4Slider_2Label = uilabel(app.UIFigure);
            app.Joint4Slider_2Label.Text = 'Joint 4';
            app.Joint4Slider_2 = uislider(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app3(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)init(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end